classdef VibPlotsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProcessedmatfileEditFieldLabel  matlab.ui.control.Label
        ProcessedmatfileEditField       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        TimedrivendataButton            matlab.ui.control.Button
        ProbabilitydistributionsButton  matlab.ui.control.Button
        SpectrogramsButton              matlab.ui.control.Button
        PSDButton                       matlab.ui.control.Button
        TimedomainLabel                 matlab.ui.control.Label
        FrequencydomainLabel            matlab.ui.control.Label
        IntegrateddisplacementButton    matlab.ui.control.Button
        VCcurvesButton                  matlab.ui.control.Button
        RMSbyfrequencybandButton        matlab.ui.control.Button
        StatsbyhourButton               matlab.ui.control.Button
        StatsbyweekdayButton            matlab.ui.control.Button
        TransmissibilityButton          matlab.ui.control.Button
        FromDatePickerLabel             matlab.ui.control.Label
        DatetimefilterFromDatePicker    matlab.ui.control.DatePicker
        ToDatePickerLabel               matlab.ui.control.Label
        DatetimefilterToDatePicker      matlab.ui.control.DatePicker
        DatetimefilterFromHour          matlab.ui.control.EditField
        DatetimefilterToHour            matlab.ui.control.EditField
        DatetimefilterCheckBox          matlab.ui.control.CheckBox
        DistributionDropDown            matlab.ui.control.DropDown
        IntegrateddisplacementDropDown  matlab.ui.control.DropDown
        Coherencethreshold01EditFieldLabel  matlab.ui.control.Label
        CoherencethresholdEditField     matlab.ui.control.EditField
        FreqslicesEditFieldLabel        matlab.ui.control.Label
        RMSFreqslicesEditField          matlab.ui.control.EditField
        TimeslicesEditFieldLabel        matlab.ui.control.Label
        StatsTimeslicesEditField        matlab.ui.control.EditField
        UpperPercentileEditFieldLabel   matlab.ui.control.Label
        UpperPercentileEditField        matlab.ui.control.NumericEditField
        CloseallfiguresButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        processed_filename % data file to process
    end
    
    methods (Access = private)
        
        function runVibPlots_gui(app,plot_type,settings)
            if(isempty(app.processed_filename))
                msgbox('Please select a file first');
            else
                settings.processed_file = app.processed_filename;
                settings.plots = {plot_type};
                vibPlots(settings);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fname,pathname] = uigetfile('*.mat','Select processed data file');
            app.processed_filename = strcat(pathname,filesep,fname);
            app.ProcessedmatfileEditField.Value = app.processed_filename;
        end

        % Button pushed function: TimedrivendataButton
        function TimedrivendataButtonPushed(app, event)
            runVibPlots_gui(app, 'time')
        end

        % Button pushed function: ProbabilitydistributionsButton
        function ProbabilitydistributionsButtonPushed(app, event)
            settings.prob_chart_distribution = app.DistributionDropDown.Value;
            settings.prob_threshold = app.UpperPercentileEditField.Value;
            runVibPlots_gui(app, 'distributions', settings);
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all
        end

        % Button pushed function: IntegrateddisplacementButton
        function IntegrateddisplacementButtonPushed(app, event)
            settings.integrated_direction = app.IntegrateddisplacementDropDown.Value
            runVibPlots_gui(app, 'integrated',settings);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessedmatfileEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessedmatfileEditFieldLabel.Text = 'Processed .mat file';
            app.ProcessedmatfileEditField = uieditfield(app.UIFigure, 'text');
            app.ProcessedmatfileEditField.Enable = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TimedrivendataButton = uibutton(app.UIFigure, 'push');
            app.TimedrivendataButton.ButtonPushedFcn = createCallbackFcn(app, @TimedrivendataButtonPushed, true);
            app.TimedrivendataButton.Text = 'Time-driven data';
            app.ProbabilitydistributionsButton = uibutton(app.UIFigure, 'push');
            app.ProbabilitydistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @ProbabilitydistributionsButtonPushed, true);
            app.ProbabilitydistributionsButton.Text = 'Probability distributions';
            app.SpectrogramsButton = uibutton(app.UIFigure, 'push');
            app.SpectrogramsButton.Text = 'Spectrograms';
            app.PSDButton = uibutton(app.UIFigure, 'push');
            app.PSDButton.Text = 'PSD';
            app.TimedomainLabel = uilabel(app.UIFigure);
            app.TimedomainLabel.Text = 'Time domain';
            app.FrequencydomainLabel = uilabel(app.UIFigure);
            app.FrequencydomainLabel.Text = 'Frequency domain';
            app.IntegrateddisplacementButton = uibutton(app.UIFigure, 'push');
            app.IntegrateddisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrateddisplacementButtonPushed, true);
            app.IntegrateddisplacementButton.Text = 'Integrated displacement';
            app.VCcurvesButton = uibutton(app.UIFigure, 'push');
            app.VCcurvesButton.Text = 'VC curves';
            app.RMSbyfrequencybandButton = uibutton(app.UIFigure, 'push');
            app.RMSbyfrequencybandButton.Text = 'RMS by frequency band';
            app.StatsbyhourButton = uibutton(app.UIFigure, 'push');
            app.StatsbyhourButton.Text = 'Stats by hour';
            app.StatsbyweekdayButton = uibutton(app.UIFigure, 'push');
            app.StatsbyweekdayButton.Text = 'Stats by weekday';
            app.TransmissibilityButton = uibutton(app.UIFigure, 'push');
            app.TransmissibilityButton.Text = 'Transmissibility';
            app.FromDatePickerLabel = uilabel(app.UIFigure);
            app.FromDatePickerLabel.Text = 'From';
            app.DatetimefilterFromDatePicker = uidatepicker(app.UIFigure);
            app.ToDatePickerLabel = uilabel(app.UIFigure);
            app.ToDatePickerLabel.Text = 'To';
            app.DatetimefilterToDatePicker = uidatepicker(app.UIFigure);
            app.DatetimefilterFromHour = uieditfield(app.UIFigure, 'text');
            app.DatetimefilterFromHour.Value = '00:00';
            app.DatetimefilterToHour = uieditfield(app.UIFigure, 'text');
            app.DatetimefilterToHour.Value = '00:00';
            app.DatetimefilterCheckBox = uicheckbox(app.UIFigure);
            app.DatetimefilterCheckBox.Text = 'Date / time filter';
            app.DistributionDropDown = uidropdown(app.UIFigure);
            app.DistributionDropDown.Items = {'Lognormal'};
            app.DistributionDropDown.Value = 'Lognormal';
            app.IntegrateddisplacementDropDown = uidropdown(app.UIFigure);
            app.IntegrateddisplacementDropDown.Items = {'increasing', 'decreasing'};
            app.IntegrateddisplacementDropDown.Value = 'increasing';
            app.Coherencethreshold01EditFieldLabel = uilabel(app.UIFigure);
            app.Coherencethreshold01EditFieldLabel.Text = 'Coherence threshold (0-1)';
            app.CoherencethresholdEditField = uieditfield(app.UIFigure, 'text');
            app.CoherencethresholdEditField.Value = '0.5';
            app.FreqslicesEditFieldLabel = uilabel(app.UIFigure);
            app.FreqslicesEditFieldLabel.Text = 'Freq. slices';
            app.RMSFreqslicesEditField = uieditfield(app.UIFigure, 'text');
            app.RMSFreqslicesEditField.Value = '0:50:250';
            app.TimeslicesEditFieldLabel = uilabel(app.UIFigure);
            app.TimeslicesEditFieldLabel.Text = 'Time slices';
            app.StatsTimeslicesEditField = uieditfield(app.UIFigure, 'text');
            app.StatsTimeslicesEditField.Value = '0 3 8 16 24';
            app.UpperPercentileEditFieldLabel = uilabel(app.UIFigure);
            app.UpperPercentileEditFieldLabel.Text = 'Upper Percentile';
            app.UpperPercentileEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperPercentileEditField.Value = 0.99;
            app.CloseallfiguresButton = uibutton(app.UIFigure, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VibPlotsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end